classdef auxapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NormalizationButton           matlab.ui.control.Button
        AinnerboundaryEditFieldLabel  matlab.ui.control.Label
        AinnerboundaryEditField       matlab.ui.control.NumericEditField
        BinnerboundaryEditFieldLabel  matlab.ui.control.Label
        BinnerboundaryEditField       matlab.ui.control.NumericEditField
        CinnerboundaryEditFieldLabel  matlab.ui.control.Label
        CinnerboundaryEditField       matlab.ui.control.NumericEditField
        DinnerboundaryEditFieldLabel  matlab.ui.control.Label
        DinnerboundaryEditField       matlab.ui.control.NumericEditField
        AouterboundaryEditFieldLabel  matlab.ui.control.Label
        AouterboundaryEditField       matlab.ui.control.NumericEditField
        BouterboundaryEditFieldLabel  matlab.ui.control.Label
        BouterboundaryEditField       matlab.ui.control.NumericEditField
        CouterboundaryEditFieldLabel  matlab.ui.control.Label
        CouterboundaryEditField       matlab.ui.control.NumericEditField
        DouterboundaryEditFieldLabel  matlab.ui.control.Label
        DouterboundaryEditField       matlab.ui.control.NumericEditField
        ConfirmButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
        frommain % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Diffusion, datafrommain)
            app.CallingApp = Diffusion;
            app.frommain = datafrommain; 
            if strcmp(app.frommain{1,3},'A')
                app.NormalizationButton.Enable = 'off';
                app.AinnerboundaryEditField.Enable = 'on';
                app.AouterboundaryEditField.Enable = 'on';
                app.BinnerboundaryEditField.Enable = 'off';
                app.BouterboundaryEditField.Enable = 'off';
                app.CinnerboundaryEditField.Enable = 'off';
                app.CouterboundaryEditField.Enable = 'off';
                app.DinnerboundaryEditField.Enable = 'off';
                app.DouterboundaryEditField.Enable = 'off';
            elseif strcmp(app.frommain{1,3},'B')
                app.NormalizationButton.Enable = 'off';
                app.AinnerboundaryEditField.Enable = 'off';
                app.AouterboundaryEditField.Enable = 'off';
                app.BinnerboundaryEditField.Enable = 'on';
                app.BouterboundaryEditField.Enable = 'on';
                app.CinnerboundaryEditField.Enable = 'off';
                app.CouterboundaryEditField.Enable = 'off';
                app.DinnerboundaryEditField.Enable = 'off';
                app.DouterboundaryEditField.Enable = 'off';
            elseif strcmp(app.frommain{1,3},'C')
                app.NormalizationButton.Enable = 'off';
                app.AinnerboundaryEditField.Enable = 'off';
                app.AouterboundaryEditField.Enable = 'off';
                app.BinnerboundaryEditField.Enable = 'off';
                app.BouterboundaryEditField.Enable = 'off';
                app.CinnerboundaryEditField.Enable = 'on';
                app.CouterboundaryEditField.Enable = 'on';
                app.DinnerboundaryEditField.Enable = 'off';
                app.DouterboundaryEditField.Enable = 'off';
            elseif strcmp(app.frommain{1,3},'D')
                app.NormalizationButton.Enable = 'off';
                app.AinnerboundaryEditField.Enable = 'off';
                app.AouterboundaryEditField.Enable = 'off';
                app.BinnerboundaryEditField.Enable = 'off';
                app.BouterboundaryEditField.Enable = 'off';
                app.CinnerboundaryEditField.Enable = 'off';
                app.CouterboundaryEditField.Enable = 'off';
                app.DinnerboundaryEditField.Enable = 'on';
                app.DouterboundaryEditField.Enable = 'on';
            elseif strcmp(app.frommain{1,3},'AB')
                app.AinnerboundaryEditField.Enable = 'on';
                app.AouterboundaryEditField.Enable = 'on';
                app.BinnerboundaryEditField.Enable = 'on';
                app.BouterboundaryEditField.Enable = 'on';
                app.CinnerboundaryEditField.Enable = 'off';
                app.CouterboundaryEditField.Enable = 'off';
                app.DinnerboundaryEditField.Enable = 'off';
                app.DouterboundaryEditField.Enable = 'off';
                app.ConfirmButton.Enable = 'off';
            elseif strcmp(app.frommain{1,3},'ABC')
                app.AinnerboundaryEditField.Enable = 'on';
                app.AouterboundaryEditField.Enable = 'on';
                app.BinnerboundaryEditField.Enable = 'on';
                app.BouterboundaryEditField.Enable = 'on';
                app.CinnerboundaryEditField.Enable = 'on';
                app.CouterboundaryEditField.Enable = 'on';
                app.DinnerboundaryEditField.Enable = 'off';
                app.DouterboundaryEditField.Enable = 'off';
                app.ConfirmButton.Enable = 'off';
            elseif strcmp(app.frommain{1,3},'ABCD')
                app.AinnerboundaryEditField.Enable = 'on';
                app.AouterboundaryEditField.Enable = 'on';
                app.BinnerboundaryEditField.Enable = 'on';
                app.BouterboundaryEditField.Enable = 'on';
                app.CinnerboundaryEditField.Enable = 'on';
                app.CouterboundaryEditField.Enable = 'on';
                app.DinnerboundaryEditField.Enable = 'on';
                app.DouterboundaryEditField.Enable = 'on';
                app.ConfirmButton.Enable = 'off';
            end
        end

        % Button pushed function: NormalizationButton
        function NormalizationButtonPushed(app, event)
            if strcmp(app.frommain{1,3},'AB')
                A_1 = app.AinnerboundaryEditField.Value;
                A_2 = app.AouterboundaryEditField.Value;
                B_1 = app.BinnerboundaryEditField.Value;
                B_2 = app.BouterboundaryEditField.Value;
                A_11 = A_1/(A_1+B_1);
                A_22 = A_2/(A_2+B_2);
                B_11 = B_1/(A_1+B_1);
                B_22 = B_2/(A_2+B_2);
                app.AinnerboundaryEditField.Value = A_11;
                app.AouterboundaryEditField.Value = A_22;
                app.BinnerboundaryEditField.Value = B_11;
                app.BouterboundaryEditField.Value = B_22;
                
            elseif strcmp(app.frommain{1,3},'ABC')
                A_1 = app.AinnerboundaryEditField.Value;
                A_2 = app.AouterboundaryEditField.Value;
                B_1 = app.BinnerboundaryEditField.Value;
                B_2 = app.BouterboundaryEditField.Value;
                C_1 = app.CinnerboundaryEditField.Value;
                C_2 = app.CouterboundaryEditField.Value;
                A_11 = A_1/(A_1+B_1+C_1);
                A_22 = A_2/(A_2+B_2+C_2);
                B_11 = B_1/(A_1+B_1+C_1);
                B_22 = B_2/(A_2+B_2+C_2);
                C_11 = C_1/(A_1+B_1+C_1);
                C_22 = C_2/(A_2+B_2+C_2);
                app.AinnerboundaryEditField.Value = A_11;
                app.AouterboundaryEditField.Value = A_22;
                app.BinnerboundaryEditField.Value = B_11;
                app.BouterboundaryEditField.Value = B_22;
                app.CinnerboundaryEditField.Value = C_11;
                app.CouterboundaryEditField.Value = C_22;
                
            elseif strcmp(app.frommain{1,3},'ABCD')
                A_1 = app.AinnerboundaryEditField.Value;
                A_2 = app.AouterboundaryEditField.Value;
                B_1 = app.BinnerboundaryEditField.Value;
                B_2 = app.BouterboundaryEditField.Value;
                C_1 = app.CinnerboundaryEditField.Value;
                C_2 = app.CouterboundaryEditField.Value;
                D_1 = app.DinnerboundaryEditField.Value;
                D_2 = app.DouterboundaryEditField.Value;
                A_11 = A_1/(A_1+B_1+C_1+D_1);
                A_22 = A_2/(A_2+B_2+C_2+D_2);
                B_11 = B_1/(A_1+B_1+C_1+D_1);
                B_22 = B_2/(A_2+B_2+C_2+D_2);
                C_11 = C_1/(A_1+B_1+C_1+D_1);
                C_22 = C_2/(A_2+B_2+C_2+D_2);
                D_11 = D_1/(A_1+B_1+C_1+D_1);
                D_22 = D_2/(A_2+B_2+C_2+D_2);
                app.AinnerboundaryEditField.Value = A_11;
                app.AouterboundaryEditField.Value = A_22;
                app.BinnerboundaryEditField.Value = B_11;
                app.BouterboundaryEditField.Value = B_22;
                app.CinnerboundaryEditField.Value = C_11;
                app.CouterboundaryEditField.Value = C_22;
                app.DinnerboundaryEditField.Value = D_11;
                app.DouterboundaryEditField.Value = D_22;
            end
            app.ConfirmButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.EdityourinputButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if strcmp(app.frommain{1,3},'A')
                data2main = [app.AinnerboundaryEditField.Value,app.AouterboundaryEditField.Value;0,0;0,0;0,0];
            elseif strcmp(app.frommain{1,3},'B')
                data2main = [0,0;app.BinnerboundaryEditField.Value,app.BouterboundaryEditField.Value;0,0;0,0];
            elseif strcmp(app.frommain{1,3},'C')
                data2main = [0,0;0,0;app.CinnerboundaryEditField.Value,app.CouterboundaryEditField.Value;0,0];
            elseif strcmp(app.frommain{1,3},'D')
                data2main = [0,0;0,0;0,0;app.DinnerboundaryEditField.Value,app.DouterboundaryEditField.Value];
            elseif strcmp(app.frommain{1,3},'AB')
                data2main = [app.AinnerboundaryEditField.Value,app.AouterboundaryEditField.Value;app.BinnerboundaryEditField.Value,app.BouterboundaryEditField.Value;0,0;0,0];
            elseif strcmp(app.frommain{1,3},'ABC')
                data2main = [app.AinnerboundaryEditField.Value,app.AouterboundaryEditField.Value;app.BinnerboundaryEditField.Value,app.BouterboundaryEditField.Value;app.CinnerboundaryEditField.Value,app.CouterboundaryEditField.Value;0,0];
            elseif strcmp(app.frommain{1,3},'ABCD')
                data2main = [app.AinnerboundaryEditField.Value,app.AouterboundaryEditField.Value;app.BinnerboundaryEditField.Value,app.BouterboundaryEditField.Value;app.CinnerboundaryEditField.Value,app.CouterboundaryEditField.Value;app.DinnerboundaryEditField.Value,app.DouterboundaryEditField.Value];
            end
            importauxdata(app.CallingApp,data2main);
            app.CallingApp.EdityourinputButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NormalizationButton = uibutton(app.UIFigure, 'push');
            app.NormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizationButtonPushed, true);
            app.NormalizationButton.Text = 'Normalization';
            app.AinnerboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.AinnerboundaryEditFieldLabel.Text = 'A inner boundary';
            app.AinnerboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinnerboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.BinnerboundaryEditFieldLabel.Text = 'B inner boundary';
            app.BinnerboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.CinnerboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.CinnerboundaryEditFieldLabel.Text = 'C inner boundary';
            app.CinnerboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.DinnerboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.DinnerboundaryEditFieldLabel.Text = 'D inner boundary';
            app.DinnerboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.AouterboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.AouterboundaryEditFieldLabel.Text = 'A outer boundary';
            app.AouterboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.BouterboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.BouterboundaryEditFieldLabel.Text = 'B outer boundary';
            app.BouterboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.CouterboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.CouterboundaryEditFieldLabel.Text = 'C outer boundary';
            app.CouterboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.DouterboundaryEditFieldLabel = uilabel(app.UIFigure);
            app.DouterboundaryEditFieldLabel.Text = 'D outer boundary';
            app.DouterboundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auxapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end