classdef mat2csv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        matconverttocsvLabel      matlab.ui.control.Label
        MatlabfileEditFieldLabel  matlab.ui.control.Label
        MatlabfileEditField       matlab.ui.control.EditField
        csvfileEditFieldLabel     matlab.ui.control.Label
        csvfileEditField          matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        BrowseButton_2            matlab.ui.control.Button
        ConvertButton             matlab.ui.control.Button
        DoneLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DoneLabel.Visible = 'off';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                app.MatlabfileEditField.Value = '';
            else
                app.MatlabfileEditField.Value = fullfile(path,file);
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [file,path] = uiputfile('*.csv', "Save Message" );
            if isequal(file,0)
                app.csvfileEditField.Value = '';
            else
                app.csvfileEditField.Value = fullfile(path,file);
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            T1 = clock;
            load(app.MatlabfileEditField.Value);
            for i=1:length(temp)
                temp{i,1}=temp{i,1}';
                writematrix(temp{i,2},app.csvfileEditField.Value,'WriteMode','append');
                writematrix(temp{i,1},app.csvfileEditField.Value,'WriteMode','append');
                writematrix('----',app.csvfileEditField.Value,'WriteMode','append');
            end
            app.DoneLabel.Visible = 'off';
            T2 = clock;
            time_elapsed = etime(T2,T1);
            app.DoneLabel.Text= join(['Done! The previous run lasted ', num2str(time_elapsed),' seconds']);
            app.DoneLabel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.matconverttocsvLabel = uilabel(app.UIFigure);
            app.matconverttocsvLabel.Text = '.mat convert to .csv';
            app.MatlabfileEditFieldLabel = uilabel(app.UIFigure);
            app.MatlabfileEditFieldLabel.Text = 'Matlab file';
            app.MatlabfileEditField = uieditfield(app.UIFigure, 'text');
            app.csvfileEditFieldLabel = uilabel(app.UIFigure);
            app.csvfileEditFieldLabel.Text = 'csv file';
            app.csvfileEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.DoneLabel = uilabel(app.UIFigure);
            app.DoneLabel.Text = 'Done!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mat2csv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end