classdef pointpick < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ConfirmButton  matlab.ui.control.Button
        resetButton    matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        x = 0 % x position
        y = 0 % y position
        CallingApp;
        frommain % Description
        verticalline;
    end

    methods (Access = private)
        
        function func(app, ax, eve)
            app.x = ax.CurrentPoint(1,1); % to access x and y outside the callback
            app.y = ax.CurrentPoint(1,2);
            [val,idx]=min(abs(app.CallingApp.Length_profile-app.x));
            if app.CallingApp.Length_picked_for_fitting(idx,1) ~= 0
                delete(app.verticalline(idx));
                app.CallingApp.Length_picked_for_fitting(idx,:)=zeros;
                app.CallingApp.measure_picked_for_fitting(idx,:)=zeros;
                app.CallingApp.error_picked_for_fitting(idx,:)=zeros;
            end
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Diffusion, datafrommain)
            app.CallingApp = Diffusion;
            app.frommain = datafrommain;
            app.verticalline = [];
            %% inital the diagram 
            ax1Chil = app.CallingApp.UIAxes.Children;  
            copyobj(ax1Chil, app.UIAxes);
            for i = 1:length(app.CallingApp.Length_profile)
                app.verticalline(i)=xline(app.UIAxes,app.CallingApp.Length_profile(i) ,':');
            end
            set(app.UIAxes.Children,'HitTest','off');
            app.UIAxes.YLim = app.CallingApp.UIAxes.YLim;
            app.UIAxes.XLim = app.CallingApp.UIAxes.XLim;
            

            if strcmp(app.CallingApp.SystemDropDown.Value,'4comp')
                if strcmp(app.CallingApp.Switch2.Value,'multi')
                    if strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile,app.CallingApp.C_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error,app.CallingApp.C_error,app.CallingApp.B_error,app.CallingApp.A_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile,app.CallingApp.C_measured_profile,app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error,app.CallingApp.C_error,app.CallingApp.A_error,app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile,app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error,app.CallingApp.A_error,app.CallingApp.B_error,app.CallingApp.C_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'D')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile,app.CallingApp.C_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.D_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error,app.CallingApp.C_error,app.CallingApp.B_error,app.CallingApp.D_error];
                    end
                elseif strcmp(app.CallingApp.Switch2.Value,'single')
                    if strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'D')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
                    end
                end    
            elseif strcmp(app.CallingApp.SystemDropDown.Value,'3comp')
                if strcmp(app.CallingApp.Switch2.Value,'multi')
                    if strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error,app.CallingApp.B_error,app.CallingApp.A_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile,app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error,app.CallingApp.A_error,app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error,app.CallingApp.B_error,app.CallingApp.C_error];
                    end
                elseif strcmp(app.CallingApp.Switch2.Value,'single')   
                    if strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
                    end
                end
            elseif strcmp(app.CallingApp.SystemDropDown.Value,'2comp')
               if strcmp(app.CallingApp.Switch2.Value,'multi')
                   app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                   app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile,app.CallingApp.A_measured_profile];
                   app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error,app.CallingApp.A_error];
               elseif strcmp(app.CallingApp.Switch2.Value,'single')   
                   if strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'B')
                       app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                       app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile];
                       app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error];
                   elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'A')
                       app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                       app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                       app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
                   end
               end
            elseif strcmp(app.CallingApp.SystemDropDown.Value,'1comp')
                app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
            end

            app.UIAxes.ButtonDownFcn = @app.func;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.CallingApp.Length_picked_for_fitting = app.CallingApp.Length_picked_for_fitting(any(app.CallingApp.Length_picked_for_fitting,2),:);
            app.CallingApp.measure_picked_for_fitting = app.CallingApp.measure_picked_for_fitting(any(app.CallingApp.measure_picked_for_fitting,2),:);
            app.CallingApp.error_picked_for_fitting = app.CallingApp.error_picked_for_fitting(any(app.CallingApp.error_picked_for_fitting,2),:);
            
            if isempty(app.CallingApp.Length_picked_for_fitting)
                f = errordlg('You removed all spots from fitting. There might be some problem. DIFFUSUP adds all spots back.','Error');
                delete(app);
            else
                delete(app);
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.verticalline = [];
            %% inital the diagram 
            ax1Chil = app.CallingApp.UIAxes.Children; 
            cla(app.UIAxes);
            copyobj(ax1Chil, app.UIAxes);
            for i = 1:length(app.CallingApp.Length_profile)
                app.verticalline(i)=xline(app.UIAxes,app.CallingApp.Length_profile(i) ,':');
            end
            set(app.UIAxes.Children,'HitTest','off');
            app.UIAxes.YLim = app.CallingApp.UIAxes.YLim;
            app.UIAxes.XLim = app.CallingApp.UIAxes.XLim;
            

            if strcmp(app.CallingApp.SystemDropDown.Value,'4comp')
                if strcmp(app.CallingApp.Switch2.Value,'multi')
                    if strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile,app.CallingApp.C_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error,app.CallingApp.C_error,app.CallingApp.B_error,app.CallingApp.A_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile,app.CallingApp.C_measured_profile,app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error,app.CallingApp.C_error,app.CallingApp.A_error,app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile,app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error,app.CallingApp.A_error,app.CallingApp.B_error,app.CallingApp.C_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'D')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile,app.CallingApp.C_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.D_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error,app.CallingApp.C_error,app.CallingApp.B_error,app.CallingApp.D_error];
                    end
                elseif strcmp(app.CallingApp.Switch2.Value,'single')
                    if strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'D')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.D_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.D_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
                    end
                end    
            elseif strcmp(app.CallingApp.SystemDropDown.Value,'3comp')
                if strcmp(app.CallingApp.Switch2.Value,'multi')
                    if strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error,app.CallingApp.B_error,app.CallingApp.A_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile,app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error,app.CallingApp.A_error,app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.DependentelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile,app.CallingApp.B_measured_profile,app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error,app.CallingApp.B_error,app.CallingApp.C_error];
                    end
                elseif strcmp(app.CallingApp.Switch2.Value,'single')   
                    if strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'C')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.C_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.C_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'B')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error];
                    elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'A')
                        app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                        app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                        app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
                    end
                end
            elseif strcmp(app.CallingApp.SystemDropDown.Value,'2comp')
               if strcmp(app.CallingApp.Switch2.Value,'multi')
                   app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                   app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile,app.CallingApp.A_measured_profile];
                   app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error,app.CallingApp.A_error];
               elseif strcmp(app.CallingApp.Switch2.Value,'single')   
                   if strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'B')
                       app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                       app.CallingApp.measure_picked_for_fitting=[app.CallingApp.B_measured_profile];
                       app.CallingApp.error_picked_for_fitting=[app.CallingApp.B_error];
                   elseif strcmp(app.CallingApp.SingleelementchoiceDropDown.Value,'A')
                       app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                       app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                       app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
                   end
               end
            elseif strcmp(app.CallingApp.SystemDropDown.Value,'1comp')
                app.CallingApp.Length_picked_for_fitting=app.CallingApp.Length_profile;
                app.CallingApp.measure_picked_for_fitting=[app.CallingApp.A_measured_profile];
                app.CallingApp.error_picked_for_fitting=[app.CallingApp.A_error];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'diffusion profile')
            xlabel(app.UIAxes, 'Length(μm)')
            ylabel(app.UIAxes, 'Xelements')
            app.UIAxes.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pointpick(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end